classdef EdgeDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FigurePanel                   matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        ButtonPanel                   matlab.ui.container.Panel
        OpenFileButton                matlab.ui.control.Button
        TurntograyimageButton         matlab.ui.control.Button
        TurntoBWimageButton           matlab.ui.control.Button
        RemovesmalltunnelsButton      matlab.ui.control.Button
        SplitthestonesButton          matlab.ui.control.Button
        RemoveincompletestonesButton  matlab.ui.control.Button
        ShoworiginalwithborderandlabelsButton  matlab.ui.control.Button
        ChooseaspecificstonetoshowLabel  matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        ShowstoneButton               matlab.ui.control.Button
        CodeofstoneLabel_2            matlab.ui.control.Label
        CodeofstoneEditField          matlab.ui.control.NumericEditField
        PerimetersEditFieldLabel      matlab.ui.control.Label
        PerimetersEditField           matlab.ui.control.NumericEditField
        AreaEditFieldLabel            matlab.ui.control.Label
        AreaEditField                 matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFileButton
        function OpenFile(app, event)
            global I;
            [filename, pathname] = uigetfile({'*.bmp';'*.jpeg';'*.jpg';'*.png'}, 'Open a picture');
            fullpath = [pathname filename];
            I = imread(fullpath);
            plt = imshow(I, 'Parent', app.UIAxes, ...
                'XData', [app.UIAxes.Position(1) app.UIAxes.Position(3)], ...
                'YData', [app.UIAxes.Position(2) app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 plt.XData(2)];
            app.UIAxes.YLim = [0 plt.YData(2)];
        end

        % Button pushed function: TurntograyimageButton
        function TurnGrayAndFilter(app, event)
            global Igray;
            global I;
            % turn to gray image
            Ip = im2double(I);
            Ip = rgb2gray(Ip);
            
            % filter -- midfilter, using median filter, remove the noise
            Igray = medfilt2(Ip);
            plt = imshow(Igray, 'Parent', app.UIAxes, ...
                'XData', [app.UIAxes.Position(1) app.UIAxes.Position(3)], ...
                'YData', [app.UIAxes.Position(2) app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 plt.XData(2)];
            app.UIAxes.YLim = [0 plt.YData(2)];
        end

        % Button pushed function: TurntoBWimageButton
        function TurnBW(app, event)
            global Igray;
            global Ibw;
            % Kmeans compute the treshhold to turn the gray image to BW image
            idx = kmeans(Igray(:), 2);
            % gm = fitgmdist(Ip(:),2);
            % idx = cluster(gm,Ip(:));
            thresh = min(max(Igray(idx == 1)), max(Igray(idx == 2)));
            Ip = imsharpen(Igray, "Threshold", thresh);
            bw = edge(Ip, "Canny");
            Ip = imbinarize(Ip, thresh);
            Ip = im2double(Ip);
            
            % Fill the imcomplete stones.
            Ip = logical(bw) | logical(Ip);
            
            % imfill to fill the hole in each image
            Ibw = imfill(Ip, "holes");
            plt = imshow(Ibw, 'Parent', app.UIAxes, ...
                'XData', [app.UIAxes.Position(1) app.UIAxes.Position(3)], ...
                'YData', [app.UIAxes.Position(2) app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 plt.XData(2)];
            app.UIAxes.YLim = [0 plt.YData(2)];
        end

        % Button pushed function: RemovesmalltunnelsButton
        function RemoveSmallTunnel(app, event)
            global Ibw;
            global IbwRmvTnl;
            % remotetunnal to remove the small tunnels in the image.
            % imerode then imdilate
            se = strel("disk", 2);
            IbwRmvTnl = imerode(Ibw, se);
            IbwRmvTnl = imdilate(IbwRmvTnl, strel("disk", 3));
            
            plt = imshow(IbwRmvTnl, 'Parent', app.UIAxes, ...
                'XData', [app.UIAxes.Position(1) app.UIAxes.Position(3)], ...
                'YData', [app.UIAxes.Position(2) app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 plt.XData(2)];
            app.UIAxes.YLim = [0 plt.YData(2)];
        end

        % Button pushed function: SplitthestonesButton
        function SplitStone(app, event)
            global IbwRmvTnl;
            global IbwSplit;
            % watershed_fine to generate edge for adjoint stones
            % https://blogs.mathworks.com/steve/2013/11/19/watershed-transform-question-from-tech-support/
            D = -bwdist(~IbwRmvTnl);
            MinGraph = (D - min(D(:))) / (max(D(:) - min(D(:))));
            mask = imextendedmin(MinGraph, 0.025);
            D2 = imimposemin(D, mask);
            Ld = watershed(D2);
            IbwSplit = IbwRmvTnl;
            IbwSplit(Ld == 0) = 0;
            
            plt = imshow(IbwSplit, 'Parent', app.UIAxes, ...
                'XData', [app.UIAxes.Position(1) app.UIAxes.Position(3)], ...
                'YData', [app.UIAxes.Position(2) app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 plt.XData(2)];
            app.UIAxes.YLim = [0 plt.YData(2)];
        end

        % Button pushed function: RemoveincompletestonesButton
        function RemoveBorderStone(app, event)
            global IbwSplit;
            global IbwClrBrd;
            % remove the incomplete stones on the border
            IbwClrBrd = imclearborder(IbwSplit);
            
            plt = imshow(IbwClrBrd, 'Parent', app.UIAxes, ...
                'XData', [app.UIAxes.Position(1) app.UIAxes.Position(3)], ...
                'YData', [app.UIAxes.Position(2) app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 plt.XData(2)];
            app.UIAxes.YLim = [0 plt.YData(2)];
        end

        % Button pushed function: 
        % ShoworiginalwithborderandlabelsButton
        function ShowResult(app, event)
            global IbwClrBrd;
            global IResult;
            global I;
            global Label;
            global num;
            % bwlabel to lable the connected zones
            [Label, num] = bwlabel(IbwClrBrd, 4); % 4 means, only the direct right, left, top and bottom will be considered as connected
            
            % perim algo to abtract edge of the stone
            Ip = bwperim(IbwClrBrd);
            
            % show the boundary and the original image
            % seperate layers
            I = im2double(I);
            R = I(:, :, 1);
            G = I(:, :, 2);
            B = I(:, :, 3);
            R = R + Ip;
            G = G + Ip;
            B = B + Ip;
            IResult = cat(3,R,G,B);
            plt = imshow(IResult, 'Parent', app.UIAxes, ...
                'XData', [app.UIAxes.Position(1) app.UIAxes.Position(3)], ...
                'YData', [app.UIAxes.Position(2) app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 plt.XData(2)];
            app.UIAxes.YLim = [0 plt.YData(2)];
            
            % label the stone and show the number in the image
            [n, m] = size(I(:, :, 1));
            for k = 1 : num
                [r, c] = find(Label == k);
                rcenter = app.UIAxes.Position(2) + mean(r) / n * (app.UIAxes.Position(4) - app.UIAxes.Position(2)) - 4;
                ccenter = app.UIAxes.Position(1) + mean(c) / m * (app.UIAxes.Position(3) - app.UIAxes.Position(1)) - 6;
                text(app.UIAxes, ccenter, rcenter, num2str(k), 'color', 'g');              
            end
        end

        % Button pushed function: ShowstoneButton
        function ShowStone(app, event)
            global I;
            global Label;
            global num;
            stoneId = app.CodeofstoneEditField.Value;
            if((stoneId > 0) && (stoneId <= num))
                a = regionprops(Label == stoneId, 'boundingbox');
                stone = imcrop(I, a.BoundingBox);
                imshow(stone, 'Parent', app.UIAxes2);
                app.PerimetersEditField.Value = regionprops(Label == stoneId, 'perimeter').Perimeter;
                app.AreaEditField.Value = regionprops(Label == stoneId, 'area').Area;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FigurePanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.FigurePanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ButtonPanel = uipanel(app.UIFigure);
            app.OpenFileButton = uibutton(app.ButtonPanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFile, true);
            app.OpenFileButton.Text = 'Open File';
            app.TurntograyimageButton = uibutton(app.ButtonPanel, 'push');
            app.TurntograyimageButton.ButtonPushedFcn = createCallbackFcn(app, @TurnGrayAndFilter, true);
            app.TurntograyimageButton.Text = 'Turn to gray image';
            app.TurntoBWimageButton = uibutton(app.ButtonPanel, 'push');
            app.TurntoBWimageButton.ButtonPushedFcn = createCallbackFcn(app, @TurnBW, true);
            app.TurntoBWimageButton.Text = 'Turn to BW image';
            app.RemovesmalltunnelsButton = uibutton(app.ButtonPanel, 'push');
            app.RemovesmalltunnelsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSmallTunnel, true);
            app.RemovesmalltunnelsButton.Text = 'Remove small tunnels';
            app.SplitthestonesButton = uibutton(app.ButtonPanel, 'push');
            app.SplitthestonesButton.ButtonPushedFcn = createCallbackFcn(app, @SplitStone, true);
            app.SplitthestonesButton.Text = 'Split the stones';
            app.RemoveincompletestonesButton = uibutton(app.ButtonPanel, 'push');
            app.RemoveincompletestonesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBorderStone, true);
            app.RemoveincompletestonesButton.Text = 'Remove incomplete stones';
            app.ShoworiginalwithborderandlabelsButton = uibutton(app.ButtonPanel, 'push');
            app.ShoworiginalwithborderandlabelsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowResult, true);
            app.ShoworiginalwithborderandlabelsButton.Text = 'Show original with border and labels';
            app.ChooseaspecificstonetoshowLabel = uilabel(app.ButtonPanel);
            app.ChooseaspecificstonetoshowLabel.Text = 'Choose a specific stone to show ---------';
            app.UIAxes2 = uiaxes(app.ButtonPanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ShowstoneButton = uibutton(app.ButtonPanel, 'push');
            app.ShowstoneButton.ButtonPushedFcn = createCallbackFcn(app, @ShowStone, true);
            app.ShowstoneButton.Text = 'Show stone';
            app.CodeofstoneLabel_2 = uilabel(app.ButtonPanel);
            app.CodeofstoneLabel_2.Text = 'Code of stone: ';
            app.CodeofstoneEditField = uieditfield(app.ButtonPanel, 'numeric');
            app.PerimetersEditFieldLabel = uilabel(app.ButtonPanel);
            app.PerimetersEditFieldLabel.Text = 'Perimeters';
            app.PerimetersEditField = uieditfield(app.ButtonPanel, 'numeric');
            app.PerimetersEditField.Limits = [0 Inf];
            app.PerimetersEditField.Editable = 'off';
            app.AreaEditFieldLabel = uilabel(app.ButtonPanel);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.ButtonPanel, 'numeric');
            app.AreaEditField.Limits = [0 Inf];
            app.AreaEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EdgeDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end